classdef Simu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        CarKeyDistancemEditField       matlab.ui.control.NumericEditField
        CarKeyDistancemEditFieldLabel  matlab.ui.control.Label
        yEditFieldLabel                matlab.ui.control.Label
        Cbx_FHSS                       matlab.ui.control.CheckBox
        Btn_Simulate                   matlab.ui.control.Button
        Sli_Distance                   matlab.ui.control.Slider
        Cbx_Thief1                     matlab.ui.control.CheckBox
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        Sli_KeyFreq                    matlab.ui.control.Slider
        KeyFreqLabel                   matlab.ui.control.Label
        Sli_CarFreq                    matlab.ui.control.Slider
        CarFreqLabel                   matlab.ui.control.Label
        DistancemLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name= 'FHSS Simulation';

            a=100; %distance

            us= 10^-6;
            j=1;
            Tz=1*us;
            tr=0*us;
            c=3*10^8; %m
           
            zeroc=10^-9;
            rad=180/pi;

            % (x,y) is theif's position range.
            x=-250:50:250;
            y=-250:50:250;

            t = tiledlayout(app.Panel,1,1);

            [x,y]=meshgrid(x,y);

            TE=((sqrt((x - a/2).^2 + y.^2)) + (sqrt((x + a/2).^2 + y.^2)))/c + j*Tz+tr; %TE=TE(x,y)

            if (TE > 0*us)
               %z=TE(x,y)=RhE=the hopping rate that thief should at least faster then.
               z= 1 ./ (TE.*10.^3); 
            else 
               z= 0;
            end   

            ax1= nexttile(t);

            contour(ax1,x,y,z);
            
            [C,h] = contour(ax1,x,y,z);
            clabel(C,h);

        end

        % Value changing function: Sli_Distance
        function Sli_DistanceValueChanging(app, event)
            changingValue = event.Value;
       
            app.CarKeyDistancemEditField.Value = changingValue; % Set
        end

        % Value changing function: Sli_CarFreq
        function Sli_CarFreqValueChanging(app, event)
            changingValue = event.Value;
            
            %app.Gag_Car.Value = changingValue; % Set
        end

        % Value changing function: Sli_KeyFreq
        function Sli_KeyFreqValueChanging(app, event)
            changingValue = event.Value;
            
            %app.Gag_Key.Value = changingValue; % Set
        end

        % Button pushed function: Btn_Simulate
        function Btn_SimulateButtonPushed(app, event)
  
            a=app.Sli_Distance.Value; %distance
            
            us= 10^-6;
            j=1;
            Tz=1*us;
            tr=0*us;
            c=3*10^8; %m

            zeroc=10^-9;
            rad=180/pi;

            % (x,y) is theif's position range.
            x=-250:50:250;
            y=-250:50:250;

            t = tiledlayout(app.Panel,1,1);

            [x,y]=meshgrid(x,y);

            TE=((sqrt((x - a/2).^2 + y.^2)) + (sqrt((x + a/2).^2 + y.^2)))/c + j*Tz+tr; %TE=TE(x,y)

            if (TE > 0*us)
               %z=TE(x,y)=RhE=the hopping rate that thief should at least faster then.
               z= 1 ./ (TE.*10.^3); 
            else 
               z= 0;
            end

            ax1= nexttile(t);

            contour(ax1,x,y,z);
            
            [C,h] = contour(ax1,x,y,z);
            clabel(C,h);


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {545, 545};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {702, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.DistancemLabel = uilabel(app.RightPanel);
            app.DistancemLabel.Text = 'Distance (m)';
            app.CarFreqLabel = uilabel(app.RightPanel);
            app.CarFreqLabel.Enable = 'off';
            app.CarFreqLabel.Text = 'Car Freq';
            app.Sli_CarFreq = uislider(app.RightPanel);
            app.Sli_CarFreq.Limits = [-500 500];
            app.Sli_CarFreq.ValueChangingFcn = createCallbackFcn(app, @Sli_CarFreqValueChanging, true);
            app.Sli_CarFreq.Enable = 'off';
            app.Sli_CarFreq.Value = 200;
            app.KeyFreqLabel = uilabel(app.RightPanel);
            app.KeyFreqLabel.Enable = 'off';
            app.KeyFreqLabel.Text = 'Key Freq';
            app.Sli_KeyFreq = uislider(app.RightPanel);
            app.Sli_KeyFreq.Limits = [-500 500];
            app.Sli_KeyFreq.ValueChangingFcn = createCallbackFcn(app, @Sli_KeyFreqValueChanging, true);
            app.Sli_KeyFreq.Enable = 'off';
            app.Sli_KeyFreq.Value = 200;
            app.yEditField = uieditfield(app.RightPanel, 'numeric');
            app.yEditField.Enable = 'off';
            app.xEditFieldLabel = uilabel(app.RightPanel);
            app.xEditFieldLabel.Enable = 'off';
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.RightPanel, 'numeric');
            app.xEditField.Enable = 'off';
            app.Cbx_Thief1 = uicheckbox(app.RightPanel);
            app.Cbx_Thief1.Enable = 'off';
            app.Cbx_Thief1.Text = 'Thief 1';
            app.Sli_Distance = uislider(app.RightPanel);
            app.Sli_Distance.Limits = [10 100];
            app.Sli_Distance.ValueChangingFcn = createCallbackFcn(app, @Sli_DistanceValueChanging, true);
            app.Sli_Distance.Value = 100;
            app.Btn_Simulate = uibutton(app.RightPanel, 'push');
            app.Btn_Simulate.ButtonPushedFcn = createCallbackFcn(app, @Btn_SimulateButtonPushed, true);
            app.Btn_Simulate.Text = 'Simulate';
            app.Cbx_FHSS = uicheckbox(app.RightPanel);
            app.Cbx_FHSS.Text = 'FHSS';
            app.Cbx_FHSS.Value = true;
            app.yEditFieldLabel = uilabel(app.RightPanel);
            app.yEditFieldLabel.Enable = 'off';
            app.yEditFieldLabel.Text = 'y';
            app.CarKeyDistancemEditFieldLabel = uilabel(app.RightPanel);
            app.CarKeyDistancemEditFieldLabel.Text = 'Car & Key Distance (m):';
            app.CarKeyDistancemEditField = uieditfield(app.RightPanel, 'numeric');
            app.CarKeyDistancemEditField.Limits = [10 1000];
            app.CarKeyDistancemEditField.Editable = 'off';
            app.CarKeyDistancemEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end